classdef AdhesionAssayForceBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdhesionAssayForceBrowserUIFigure  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenAdhesiondatasetMenu   matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        CloseAdhesionDatasetMenu  matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        EditMenu                  matlab.ui.container.Menu
        ConfigMenu                matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        AboutMenu                 matlab.ui.container.Menu
        LeftPanel                 matlab.ui.container.Panel
        ActiveFitData             matlab.ui.control.Table
        ReducefittoonemodeButton  matlab.ui.control.Button
        AdhesionTable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        ForcePlot % Handle to force plot used inside the app
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: OpenAdhesiondatasetMenu
        function OpenAdhesiondatasetMenuSelected(app, event)
            [File, Path, fidx] = uigetfile({'*.mat;*.AdhesionDataset.mat';'*.mat';'*.AdhesionDataset.mat';'*.*'}, ...
                                      'Select File(s) to Open', ...
                                      'MultiSelect', 'off');

            AdhesionData = load(fullfile(Path, File));
            
            varorder(1,:) = {'PlateID', 'SubstrateChemistry', 'BeadChemistry', 'Media'};
            Data = AdhesionData.DetachForceTable;

            columnlist = Data.Properties.VariableNames;

            if ~contains(columnlist, 'IncludeCurve')
                Data.IncludeCurve = true(height(Data), 1);
                Data.IncludePoints = cellfun(@(x)true(size(x,1),1), Data.ForceData, 'UniformOutput', false);
            end

            Data = movevars(Data, varorder(2:end), 'after', 'PlateID');

            app.AdhesionTable.Data = Data;
            app.AdhesionTable.ColumnName = Data.Properties.VariableNames;            
            drawnow;
            app.AdhesionTable.RowName = 'numbered';            
            app.AdhesionTable.SelectionType = "row";
            drawnow;

            app.ForcePlot = figure;

            assignin("base", "AdhesionAppDataSet", Data);
            logentry(['Loaded file: ' File]);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            close(app.ForcePlot);
            app.delete;
        end

        % Button pushed function: ReducefittoonemodeButton
        function ReduceFitButtonPushed(app, event)

            force = app.ActiveFitData;

            [fitresult, gof] = ba_fit_erf(force, pct_left, weights, 1, true);
        end

        % Selection changed function: AdhesionTable
        function AdhesionTableSelectionChanged(app, event)
            selection = app.AdhesionTable.Selection;
            
            selData = app.AdhesionTable.Data(selection,:);

            myfit = selData.FitObject{1};
            forces = cell2mat(selData.ForceData);
            errforce = cell2mat(selData.ForceError);
            attachedFactor = cell2mat(selData.PctLeftData);
            weights = cell2mat(selData.Weights);%             selData.
            included = cell2mat(selData.IncludePoints);
            titlestring = join(string(table2array(selData(1, {'BeadChemistry', 'SubstrateChemistry', 'Media', 'pH'}))), '_');
            ActiveFitDataOut.fit = myfit;
            ActiveFitDataOut.forces = forces;
            ActiveFitDataOut.pctLeft = attachedFactor;
            ActiveFitDataOut.weights = weights;
            ActiveFitDataOut.IncludePoints = included;

            assignin("base", "ActiveFitDataOut", ActiveFitDataOut);

            ba_plot_fit(myfit, forces, errforce, attachedFactor, app.ForcePlot);
            title(titlestring, 'Interpreter', 'none');
            q = [forces(:) errforce(:) attachedFactor(:) weights(:) included(:)];            
            app.ActiveFitData.Data = sortrows(q,1);

        end

        % Cell edit callback: ActiveFitData
        function ActiveFitDataCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            included = logical(event.Source.Data(:,5));

            force = event.Source.Data(included,1);
            force_error = event.Source.Data(included,2);
            pct_left = event.Source.Data(included,3);
            weights = event.Source.Data(included,4);
            

            Nterms = 2;

            [newfit, gof] = ba_fit_erf(log10(force), pct_left, weights, Nterms);

            ba_plot_fit(newfit, force, force_error, pct_left, app.ForcePlot);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdhesionAssayForceBrowserUIFigure = uifigure('Visible', 'off');
            app.AdhesionAssayForceBrowserUIFigure.Name = 'Adhesion Assay Force Browser';
            app.FileMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenAdhesiondatasetMenu = uimenu(app.FileMenu);
            app.OpenAdhesiondatasetMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenAdhesiondatasetMenuSelected, true);
            app.OpenAdhesiondatasetMenu.Accelerator = 'o';
            app.OpenAdhesiondatasetMenu.Text = 'Open Adhesion dataset';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseAdhesionDatasetMenu = uimenu(app.FileMenu);
            app.CloseAdhesionDatasetMenu.Accelerator = 'w';
            app.CloseAdhesionDatasetMenu.Text = 'Close Adhesion Dataset';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Accelerator = 'q';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.EditMenu.Text = 'Edit';
            app.ConfigMenu = uimenu(app.EditMenu);
            app.ConfigMenu.Accelerator = ',';
            app.ConfigMenu.Text = 'Config';
            app.HelpMenu = uimenu(app.AdhesionAssayForceBrowserUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.LeftPanel = uipanel(app.AdhesionAssayForceBrowserUIFigure);
            app.AdhesionTable = uitable(app.LeftPanel);
            app.AdhesionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AdhesionTable.ColumnRearrangeable = 'on';
            app.AdhesionTable.RowName = {};
            app.AdhesionTable.ColumnSortable = true;
            app.AdhesionTable.SelectionType = 'row';
            app.AdhesionTable.ColumnEditable = true;
            app.AdhesionTable.SelectionChangedFcn = createCallbackFcn(app, @AdhesionTableSelectionChanged, true);
            app.ReducefittoonemodeButton = uibutton(app.LeftPanel, 'push');
            app.ReducefittoonemodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceFitButtonPushed, true);
            app.ReducefittoonemodeButton.Text = {'Reduce fit to'; 'one mode'};
            app.ActiveFitData = uitable(app.LeftPanel);
            app.ActiveFitData.ColumnName = {'Force'; 'ForceError'; 'AttachFactor'; 'Weight'; 'Included'};
            app.ActiveFitData.RowName = {};
            app.ActiveFitData.ColumnSortable = true;
            app.ActiveFitData.ColumnEditable = true;
            app.ActiveFitData.CellEditCallback = createCallbackFcn(app, @ActiveFitDataCellEdit, true);
            % Show the figure after all components are created
            app.AdhesionAssayForceBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdhesionAssayForceBrowser

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.AdhesionAssayForceBrowserUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.AdhesionAssayForceBrowserUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdhesionAssayForceBrowserUIFigure)
        end
    end
end